classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        figLoad                 matlab.ui.control.UIAxes
        ClicktoloadImageButton  matlab.ui.control.Button
        figResult               matlab.ui.control.UIAxes
        BrightnessKnobLabel     matlab.ui.control.Label
        BrightnessKnob          matlab.ui.control.Knob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Calculator(app, event)


        end

        % Button pushed function: ClicktoloadImageButton
        function ClicktoloadImageButtonPushed(app, event)
            global imData;
            [file, a] = uigetfile();
            fileName = strcat(a,file);
            imData = imread(fileName);
            app.ClicktoloadImageButton.BackgroundColor = [0.0353 0.484 0.1843]; % green color
             app.ClicktoloadImageButton.Text = ' Image Loaded';
            
            imshow(imData,'Parent',app.figLoad);
        end

        % Value changed function: BrightnessKnob
        function BrightnessKnobValueChanged(app, event)
             global imData;
            value = app.BrightnessKnob.Value;
            data = imData + 255*value/100;
             imshow(data,'Parent',app.figResult);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.figLoad = uiaxes(app.UIFigure);
            title(app.figLoad, 'Title')
            xlabel(app.figLoad, 'X')
            ylabel(app.figLoad, 'Y')
            app.ClicktoloadImageButton = uibutton(app.UIFigure, 'push');
            app.ClicktoloadImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoloadImageButtonPushed, true);
            app.ClicktoloadImageButton.Text = 'Click to load Image';
            app.figResult = uiaxes(app.UIFigure);
            title(app.figResult, 'Title')
            xlabel(app.figResult, 'X')
            ylabel(app.figResult, 'Y')
            app.BrightnessKnobLabel = uilabel(app.UIFigure);
            app.BrightnessKnobLabel.Text = 'Brightness';
            app.BrightnessKnob = uiknob(app.UIFigure, 'continuous');
            app.BrightnessKnob.ValueChangedFcn = createCallbackFcn(app, @BrightnessKnobValueChanged, true);
            app.BrightnessKnob.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end