classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        x2Button     matlab.ui.control.RadioButton
        x3Button     matlab.ui.control.RadioButton
        sinxButton   matlab.ui.control.RadioButton
        tanxButton   matlab.ui.control.RadioButton
        UIAxes       matlab.ui.control.UIAxes
        RUNButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            x= linspace(-10,10,100);
           % app.x3Button.Value = true;
           if (app.x3Button.Value==true)
               y=x.^3;
               plot(app.UIAxes,x,y);
           elseif(app.x2Button.Value==true)
                y=x.^2;
               plot(app.UIAxes,x,y);
           elseif(app.sinxButton.Value==true)
                y=sin(x);
               plot(app.UIAxes,x,y);
            elseif(app.tanxButton.Value==true)
                y=tan(x);
               plot(app.UIAxes,x,y);
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.x2Button = uiradiobutton(app.ButtonGroup);
            app.x2Button.Text = 'x^2';
            app.x3Button = uiradiobutton(app.ButtonGroup);
            app.x3Button.Text = 'x^3';
            app.x3Button.Value = true;
            app.sinxButton = uiradiobutton(app.ButtonGroup);
            app.sinxButton.Text = 'sin(x)';
            app.tanxButton = uiradiobutton(app.ButtonGroup);
            app.tanxButton.Text = 'tan(x)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end